classdef PIC_PCB_ctrl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIC_PCB_ctrlUIFigure     matlab.ui.Figure
        PICPCBCONTROLLabel       matlab.ui.control.Label
        CONNECTButton            matlab.ui.control.Button
        CANCELLATIONSwitchLabel  matlab.ui.control.Label
        CANCELLATIONSwitch       matlab.ui.control.Switch
        SICSwitchLabel           matlab.ui.control.Label
        SICSwitch                matlab.ui.control.Switch
        Write                    matlab.ui.control.NumericEditField
        LASERBIASButton          matlab.ui.control.Button
        Write_2                  matlab.ui.control.NumericEditField
        CHANNELGAINButton        matlab.ui.control.Button
        Write_3                  matlab.ui.control.NumericEditField
        Write_4                  matlab.ui.control.NumericEditField
        WRITEButton              matlab.ui.control.Button
        TAPLabel                 matlab.ui.control.Label
        VALUELabel               matlab.ui.control.Label
        Write_5                  matlab.ui.control.NumericEditField
        SICPOWERButton           matlab.ui.control.Button
        Write_6                  matlab.ui.control.NumericEditField
        RXPOWERButton            matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        STATUSButton             matlab.ui.control.Button
        LOADButton               matlab.ui.control.Button
    end


    properties (Access = public)
       t;bias;chgain;delay=0.1;Tap;Tap_val;sicpower;rxpower;Tap_var=zeros(1,7);data_read;
       header          = swapbytes(uint32(hex2dec('abcd1020')));
       type_req        = swapbytes(uint32(hex2dec('00000010')));
       type_res        = swapbytes(uint32(hex2dec('00000020')));
    
      cmd_pic         = swapbytes(uint32(hex2dec('0000001A')));
      cmd_chgain      = swapbytes(uint32(hex2dec('00000010')));
      cmd_bias        = swapbytes(uint32(hex2dec('00000011')));
      cmd_slope       = swapbytes(uint32(hex2dec('00000012')));
      cmd_spower      = swapbytes(uint32(hex2dec('00000013')));
      cmd_rxpower     = swapbytes(uint32(hex2dec('00000014')));
      cmd_sic         = swapbytes(uint32(hex2dec('00000015')));
      cmd_echo        = swapbytes(uint32(hex2dec('00000016')));
      
    end


    methods (Access = private)

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
        app.t = tcpclient('localhost',1555, 'Timeout', 60);    
        end

        % Value changed function: CANCELLATIONSwitch
        function CANCELLATIONSwitchValueChanged(app, event)
           if strcmp(app.CANCELLATIONSwitch.Value, 'On');
            
              data_echo          = swapbytes(uint32(1));
              len_echo           = swapbytes(uint32(4*length(data_echo)));
              packet_echo        = uint32([app.header,app.type_req,len_echo,app.cmd_echo,data_echo]);
              write(app.t,packet_echo);
              pause(app.delay); 
              
           else 
              data_echo          = swapbytes(uint32(0));
              len_echo           = swapbytes(uint32(4*length(data_echo)));
              packet_echo        = uint32([app.header,app.type_req,len_echo,app.cmd_echo,data_echo]);
              write(app.t,packet_echo);
              pause(app.delay); 
           end  
               
        end

        % Value changed function: SICSwitch
        function SICSwitchValueChanged(app, event)
            
             if strcmp(app.SICSwitch.Value, 'On');
                 
                 data_sic           = swapbytes(uint32(1));
                 len_sic            = swapbytes(uint32(4*length(data_sic)));
                 packet_sic         = uint32([app.header,app.type_req,len_sic,app.cmd_sic,data_sic]);
                 write(app.t,packet_sic);
                 pause(app.delay);
                 
             else 
                 
                 data_sic           = swapbytes(uint32(0));
                 len_sic            = swapbytes(uint32(4*length(data_sic)));
                 packet_sic         = uint32([app.header,app.type_req,len_sic,app.cmd_sic,data_sic]);
                 write(app.t,packet_sic);
                 pause(app.delay);
                 
             end
        end

        % Value changed function: Write
        function WriteValueChanged(app, event)
            app.bias = app.Write.Value;
            
        end

        % Button pushed function: LASERBIASButton
        function LASERBIASButtonPushed(app, event)
            
            data_bias          = swapbytes(uint32(app.bias));
            len_bias           = swapbytes(uint32(4*length(data_bias)));
            packet_databias    = uint32([app.header,app.type_req,len_bias,app.cmd_bias,data_bias]);
            write(app.t,packet_databias);
            pause(app.delay);
            
            
        end

        % Value changed function: Write_2
        function Write_2ValueChanged(app, event)
            app.chgain = app.Write_2.Value;
            
        end

        % Button pushed function: CHANNELGAINButton
        function CHANNELGAINButtonPushed(app, event)
            for i = 0:1 % pos/neg selector
    for j = 0:7 % PIC lines from each side (pos/neg)
        
        p_ch            = swapbytes(uint32(i));
        ch_num          = swapbytes(uint32(j));
        gain_ch         = swapbytes(uint32(app.chgain));
        data_chgain     = [p_ch,ch_num,gain_ch];
        len_chgain      = swapbytes(uint32(4*length(data_chgain)));
        packet_chgain   = uint32([app.header,app.type_req,len_chgain,app.cmd_chgain,data_chgain]);
        write(app.t,packet_chgain);
        pause(app.delay);
    end
            end
        end

        % Callback function
        function PICSwitchValueChanged(app, event)
            
            if  strcmp(app.PICSwitch.Value,'Off');


              dac_channels = 1:7;
              tap_max_atten = app.data_read;
              tap_min_atten = zeros(length(dac_channels)); 
                                      
            channel_gain_min = 0;
            channel_gain_max = 4095;
              
              pic_channels = [1 0 0 0 1 1 0 ;   % port sign (p=1/n=0) PCB 1 
                             1 4 7 2 2 4 0 ;];            
    for j = 1:length(dac_channels)
        if j == i
            pause(0.01);
        else
            
            pic_channel        = swapbytes(uint32(dac_channels(j)));
            pic_code           = swapbytes(uint32(tap_max_atten(j)));
            app.Tap_var(j)     = tap_max_atten(j);
            data_pic           = [pic_channel, pic_code];
            len_pic            = swapbytes(uint32(4*length(data_pic)));
            packet_picoff         = uint32([app.header,app.type_req,len_pic,app.cmd_pic,data_pic]);
            write(app.t,packet_picoff);
            pause(0.1);
            
            
            p_ch            = swapbytes(uint32(pic_channels(1,j)));
            ch_num          = swapbytes(uint32(pic_channels(2,j)));
            gain_ch         = swapbytes(uint32(channel_gain_min));
            data_chgain     = [p_ch,ch_num,gain_ch];
            len_chgain      = swapbytes(uint32(4*length(data_chgain)));
            packet_chgain   = uint32([app.header,app.type_req,len_chgain,app.cmd_chgain,data_chgain]);
            write(app.t,packet_chgain);
            pause(0.1);
        end
    end   
    
    
            else 
                
            end
            
        end

        % Value changed function: Write_3
        function Write_3ValueChanged(app, event)
            app.Tap = app.Write_3.Value;
            
        end

        % Value changed function: Write_4
        function Write_4ValueChanged(app, event)
            app.Tap_val = app.Write_4.Value;
            
        end

        % Button pushed function: WRITEButton
        function WRITEButtonPushed(app, event)
            dac_channels = 1:7;
            tap_max_atten = app.data_read;
            tap_min_atten = zeros(length(dac_channels)); 
                                      
            channel_gain_min = 0;
            channel_gain_max = 4095;
              
              pic_channels = [1 0 0 0 1 1 0 ;   % port sign (p=1/n=0) PCB 1 
                              1 4 7 2 2 4 0 ;];    
            pic_channel         = swapbytes(uint32(dac_channels(app.Tap)));
            pic_code            = swapbytes(uint32(app.Tap_val));
              
    data_pic           = [pic_channel, pic_code];
    len_pic            = swapbytes(uint32(4*length(data_pic)));
    packet_pic         = uint32([app.header,app.type_req,len_pic,app.cmd_pic,data_pic]);
    write(app.t,packet_pic);
    pause(app.delay);
    tap_temp= app.Tap_val;
    app.Tap_var(app.Tap)= tap_temp;
    
    p_ch            = swapbytes(uint32(pic_channels(1,app.Tap)));
    ch_num          = swapbytes(uint32(pic_channels(2,app.Tap)));
    gain_ch         = swapbytes(uint32(channel_gain_max));
    data_chgain     = [p_ch,ch_num,gain_ch];
    len_chgain      = swapbytes(uint32(4*length(data_chgain)));
    packet_chgain   = uint32([app.header,app.type_req,len_chgain,app.cmd_chgain,data_chgain]);
    write(app.t,packet_chgain);
    pause(app.delay);
        end

        % Value changed function: Write_5
        function Write_5ValueChanged(app, event)
            app.sicpower = app.Write_5.Value;
            
        end

        % Value changed function: Write_6
        function Write_6ValueChanged(app, event)
            app.rxpower = app.Write_6.Value;
            
        end

        % Button pushed function: SICPOWERButton
        function SICPOWERButtonPushed(app, event)
            data_spower        = swapbytes(uint32(app.sicpower));
            len_spower         = swapbytes(uint32(4*length(data_spower)));
            packet_spower      = uint32([app.header,app.type_req,len_spower,app.cmd_spower,data_spower]);
            write(app.t,packet_spower);
            pause(app.delay);
        end

        % Button pushed function: RXPOWERButton
        function RXPOWERButtonPushed(app, event)
            
            rxpower             = swapbytes(uint32(app.rxpower));
            len_rxpower         = swapbytes(uint32(4*length(rxpower)));
            packet_rxpower      = uint32([app.header,app.type_req,len_rxpower,app.cmd_rxpower,rxpower]);
            write(app.t,packet_rxpower);
            pause(app.delay);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
         
            
        end

        % Callback function
        function ButtonPushed(app, event)
             
        end

        % Button pushed function: STATUSButton
        function STATUSButtonPushed(app, event)
            
            st= ['Laser Bias:     ' num2str(app.bias) newline 'Channel Gain:    ' num2str(app.chgain) newline 'SIC power:     ' num2str(app.sicpower) newline 'RX power:     ' num2str(app.rxpower) newline newline  'Tap1: ' num2str(app.Tap_var(1)) newline  'Tap2: ' num2str(app.Tap_var(2)) newline  'Tap3: ' num2str(app.Tap_var(3)) newline  'Tap4: ' num2str(app.Tap_var(4)) newline  'Tap5: ' num2str(app.Tap_var(5)) newline  'Tap6: ' num2str(app.Tap_var(6)) newline  'Tap7: ' num2str(app.Tap_var(7))];
            
            app.TextArea.Value = st;
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
         app.data_read= load(uigetfile('*.txt'));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIC_PCB_ctrlUIFigure = uifigure;
            app.PIC_PCB_ctrlUIFigure.Name = 'PIC_PCB_ctrl';
            setAutoResize(app, app.PIC_PCB_ctrlUIFigure, true)
            app.PICPCBCONTROLLabel = uilabel(app.PIC_PCB_ctrlUIFigure);
            app.PICPCBCONTROLLabel.Text = 'PIC PCB CONTROL ';
            app.CONNECTButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.CANCELLATIONSwitchLabel = uilabel(app.PIC_PCB_ctrlUIFigure);
            app.CANCELLATIONSwitchLabel.Text = 'CANCELLATION';
            app.CANCELLATIONSwitch = uiswitch(app.PIC_PCB_ctrlUIFigure, 'slider');
            app.CANCELLATIONSwitch.ValueChangedFcn = createCallbackFcn(app, @CANCELLATIONSwitchValueChanged, true);
            app.CANCELLATIONSwitch.Value = 'On';
            app.SICSwitchLabel = uilabel(app.PIC_PCB_ctrlUIFigure);
            app.SICSwitchLabel.Text = 'SIC';
            app.SICSwitch = uiswitch(app.PIC_PCB_ctrlUIFigure, 'slider');
            app.SICSwitch.ValueChangedFcn = createCallbackFcn(app, @SICSwitchValueChanged, true);
            app.Write = uieditfield(app.PIC_PCB_ctrlUIFigure, 'numeric');
            app.Write.ValueChangedFcn = createCallbackFcn(app, @WriteValueChanged, true);
            app.LASERBIASButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.LASERBIASButton.ButtonPushedFcn = createCallbackFcn(app, @LASERBIASButtonPushed, true);
            app.LASERBIASButton.Text = 'LASER BIAS';
            app.Write_2 = uieditfield(app.PIC_PCB_ctrlUIFigure, 'numeric');
            app.Write_2.ValueChangedFcn = createCallbackFcn(app, @Write_2ValueChanged, true);
            app.CHANNELGAINButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.CHANNELGAINButton.ButtonPushedFcn = createCallbackFcn(app, @CHANNELGAINButtonPushed, true);
            app.CHANNELGAINButton.Text = 'CHANNEL GAIN ';
            app.Write_3 = uieditfield(app.PIC_PCB_ctrlUIFigure, 'numeric');
            app.Write_3.ValueChangedFcn = createCallbackFcn(app, @Write_3ValueChanged, true);
            app.Write_4 = uieditfield(app.PIC_PCB_ctrlUIFigure, 'numeric');
            app.Write_4.ValueChangedFcn = createCallbackFcn(app, @Write_4ValueChanged, true);
            app.WRITEButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.WRITEButton.ButtonPushedFcn = createCallbackFcn(app, @WRITEButtonPushed, true);
            app.WRITEButton.Text = 'WRITE';
            app.TAPLabel = uilabel(app.PIC_PCB_ctrlUIFigure);
            app.TAPLabel.Text = 'TAP';
            app.VALUELabel = uilabel(app.PIC_PCB_ctrlUIFigure);
            app.VALUELabel.Text = 'VALUE';
            app.Write_5 = uieditfield(app.PIC_PCB_ctrlUIFigure, 'numeric');
            app.Write_5.ValueChangedFcn = createCallbackFcn(app, @Write_5ValueChanged, true);
            app.SICPOWERButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.SICPOWERButton.ButtonPushedFcn = createCallbackFcn(app, @SICPOWERButtonPushed, true);
            app.SICPOWERButton.Text = 'SIC POWER';
            app.Write_6 = uieditfield(app.PIC_PCB_ctrlUIFigure, 'numeric');
            app.Write_6.ValueChangedFcn = createCallbackFcn(app, @Write_6ValueChanged, true);
            app.RXPOWERButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.RXPOWERButton.ButtonPushedFcn = createCallbackFcn(app, @RXPOWERButtonPushed, true);
            app.RXPOWERButton.Text = 'RX POWER ';
            app.TextArea = uitextarea(app.PIC_PCB_ctrlUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.STATUSButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.STATUSButton.ButtonPushedFcn = createCallbackFcn(app, @STATUSButtonPushed, true);
            app.STATUSButton.Text = 'STATUS';
            app.LOADButton = uibutton(app.PIC_PCB_ctrlUIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIC_PCB_ctrl()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIC_PCB_ctrlUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIC_PCB_ctrlUIFigure)
        end
    end
end